classdef cardiopulmonary_monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RadarData                      matlab.ui.control.UIAxes
        RecieveDataSwitchLabel         matlab.ui.control.Label
        RecieveDataSwitch              matlab.ui.control.Switch
        CardiopulmonaryMonitorLabel    matlab.ui.control.Label
        RespiratoryMonitor             matlab.ui.control.UIAxes
        CardiacMonitor                 matlab.ui.control.UIAxes
        HeartRateGaugeLabel            matlab.ui.control.Label
        HeartRateGauge                 matlab.ui.control.Gauge
        ResipiratoryRateGaugeLabel     matlab.ui.control.Label
        ResipiratoryRateGauge          matlab.ui.control.Gauge
        RadarONLampLabel               matlab.ui.control.Label
        RadarONLamp                    matlab.ui.control.Lamp
        CardioONLampLabel              matlab.ui.control.Label
        CardioONLamp                   matlab.ui.control.Lamp
        ChestWallDistmSliderLabel      matlab.ui.control.Label
        ChestWallDistmSlider           matlab.ui.control.Slider
        TestDurationsEditFieldLabel    matlab.ui.control.Label
        TestDurationsEditField         matlab.ui.control.NumericEditField
        SampleWindowsEditFieldLabel    matlab.ui.control.Label
        SampleWindowsEditField         matlab.ui.control.NumericEditField
        CardiacbeatBWHzEditFieldLabel  matlab.ui.control.Label
        CardiacbeatBWHzEditField       matlab.ui.control.NumericEditField
        QRSCmplexHREditFieldLabel      matlab.ui.control.Label
        QRSCmplexHREditField           matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        radar % Description
        COM
        FPS
        dataType = 'bb';
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(strcat(pwd,'/ModuleConnector_UNIX/lib'));
            addpath(strcat(pwd,'/ModuleConnector_UNIX/include'));
            addpath(strcat(pwd,'/ModuleConnector_UNIX/matlab/'));
            addpath(strcat(pwd,'/ModuleConnector_UNIX/matlab/examples'));
            addpath(strcat(pwd,'/ModuleConnector_WIN/lib'));
            addpath(strcat(pwd,'/ModuleConnector_WIN/include'));
            addpath(strcat(pwd,'/ModuleConnector_WIN/matlab/'));
            addpath(strcat(pwd,'/ModuleConnector_WIN/matlab/examples'));
            
            % Load the library
            Lib = ModuleConnector.Library;
            Lib.libfunctions    
            
            % Radar Class Settings
            app.COM = 'COM3';
            app.FPS = 20;
            app.dataType = 'bb';
            
            app.radar = BasicRadarClassX4(app.COM, app.FPS, app.dataType);
            app.radar.open();
            app.radar.init();
            
            % Chip Settings and Configuration
            PPS = 26;
            DACmin = 949;
            DACmax = 1100;
            Iterations = 16;
            FrameStart = 0; % meters
            FrameStop = 1; % meters
            
            app.radar.radarInstance.x4driver_set_pulsesperstep(PPS);
            app.radar.radarInstance.x4driver_set_dac_min(DACmin);
            app.radar.radarInstance.x4driver_set_dac_max(DACmax);
            app.radar.radarInstance.x4driver_set_iterations(Iterations);
            app.radar.radarInstance.x4driver_set_frame_area(FrameStart,FrameStop);
            clear PPS DACmin DACmax Iterations FrameStart FrameStop
        end

        % Value changed function: RecieveDataSwitch
        function RecieveDataSwitchValueChanged(app, event)
            app.RadarONLamp.Color = [0.00,1.00,0.00];
            app.radar.start();
            rDatahandle = plot(app.RadarData, 0);
            
            % Window size for the breathing and heart rate
            RSample = zeros(1, app.SampleWindowsEditField.Value * 20);
            CSample = zeros(1, app.SampleWindowsEditField.Value * 20);
            
            % Heart and Breathing rate filters
            hrfilt = designfilt('bandpassiir', 'StopbandFrequency1', 0.4, 'PassbandFrequency1', 0.5, 'PassbandFrequency2', 4, 'StopbandFrequency2', 5, 'StopbandAttenuation1', 60, 'PassbandRipple', 1, 'StopbandAttenuation2', 60, 'SampleRate', 20);
            
            % Graph information
            ylim(app.RadarData,[0 0.1]);
            endTime = datenum(clock + [0, 0, 0, 0, 0, app.TestDurationsEditField.Value]);
            isFirstFrame = true;
            i = 0; % The frame integer
            HR = 0;
            RespIndex = 0;
            
            while(datenum(clock) < endTime)
                numPackets = app.radar.bufferSize();
                
                % Might not always get packets
                if(numPackets <= 0);
                    continue;
                end
                
                % Get frame (uses read_message_data_float)
                i = i + 1;
                [frame, ctr] = app.radar.GetFrameNormalized();
                
                % Checks if it is the first frame, then zero out using a moving average 5 steps
                if isFirstFrame
                    average = frame;
                    isFirstFrame = false;
                elseif mod(i, 100) == 0
                    average = (average + frame)/2;
                end
                frame = frame - average;
                
                % Draws the frame
                switch app.dataType
                    case 'rf'
                        rDatahandle.YData = frame;
                    case 'bb'
                        frame = frame(1:end/2) + 1i*frame(end/2 + 1:end);
                        rDatahandle.YData = abs(frame); 
                end
                
                % Find peak for respiratory rhythm
                if mod(i, app.SampleWindowsEditField.Value * app.FPS) == 0
                    [MaxResp, RespIndex] = max(frame);
                    % TODO : Draw vertical line indicating peak
                    app.ResipiratoryRateGauge.Value = RespIndex;
                end
                
                % Peak value drawn on the second graph
%                 RSample = [RSample(2:end) frame(RespIndex)];
                
%                 % Find the FFT of the RSample info to get the peak heart rate
%                 % Optional : Remove the Respiratory information of the breathing from the initial raw data
%                 RSampleFFT = fft(RSample);
%                 
%                 % Filter the raw data with the heart rate filter and find the peak
%                 HRData = filter(hrfilt,RSample);
%                 HR = (HR + max(RSampleFFT(a:b)))/2 % where a and b are the filter locations
%                 
%                 % Display the heart rate diagram (Most difficult)
%                 % Do another moving average filter for the heart rate with window size
%                 
%                 % Scan for a high change in the heart rate diagram, to obtain timing.
%                 % Average with previous estimate of new heart beat
%                 
%                 % At heart beat, probe for a peak beat frequency
%                 % Again, moving average with previous estimate of peak beat frequency
%                 
%                 % Design a high pass filter around the peak beat frequency, sample for QRS Ratio / Heart Duration
%                 % Draw the single heart beat at the sample point
                drawnow;
            end
            app.radar.stop();
            app.RadarONLamp.Color = [1.00,0.00,0.00];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.radar.close();
            delete(app);
            cls;
            clear;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RadarData = uiaxes(app.UIFigure);
            title(app.RadarData, 'Sensor Input')
            xlabel(app.RadarData, 'Distance')
            ylabel(app.RadarData, 'Amplitude')
            app.RecieveDataSwitchLabel = uilabel(app.UIFigure);
            app.RecieveDataSwitchLabel.Text = 'Recieve Data';
            app.RecieveDataSwitch = uiswitch(app.UIFigure, 'slider');
            app.RecieveDataSwitch.ValueChangedFcn = createCallbackFcn(app, @RecieveDataSwitchValueChanged, true);
            app.CardiopulmonaryMonitorLabel = uilabel(app.UIFigure);
            app.CardiopulmonaryMonitorLabel.Text = 'Cardiopulmonary Monitor';
            app.RespiratoryMonitor = uiaxes(app.UIFigure);
            title(app.RespiratoryMonitor, 'Respiratory Pulse (Estimated)')
            xlabel(app.RespiratoryMonitor, 'Time')
            ylabel(app.RespiratoryMonitor, 'Offset')
            app.CardiacMonitor = uiaxes(app.UIFigure);
            title(app.CardiacMonitor, 'Cardiac Pulse (Estimated)')
            xlabel(app.CardiacMonitor, 'Time')
            ylabel(app.CardiacMonitor, 'Offset')
            app.HeartRateGaugeLabel = uilabel(app.UIFigure);
            app.HeartRateGaugeLabel.Text = 'Heart Rate';
            app.HeartRateGauge = uigauge(app.UIFigure, 'circular');
            app.ResipiratoryRateGaugeLabel = uilabel(app.UIFigure);
            app.ResipiratoryRateGaugeLabel.Text = 'Resipiratory Rate';
            app.ResipiratoryRateGauge = uigauge(app.UIFigure, 'circular');
            app.RadarONLampLabel = uilabel(app.UIFigure);
            app.RadarONLampLabel.Text = 'Radar ON';
            app.RadarONLamp = uilamp(app.UIFigure);
            app.CardioONLampLabel = uilabel(app.UIFigure);
            app.CardioONLampLabel.Text = 'Cardio ON';
            app.CardioONLamp = uilamp(app.UIFigure);
            app.ChestWallDistmSliderLabel = uilabel(app.UIFigure);
            app.ChestWallDistmSliderLabel.Text = 'Chest Wall Dist. (m)';
            app.ChestWallDistmSlider = uislider(app.UIFigure);
            app.ChestWallDistmSlider.Limits = [0 1];
            app.ChestWallDistmSlider.MajorTickLabels = {'0', '', '', '', '', '1'};
            app.TestDurationsEditFieldLabel = uilabel(app.UIFigure);
            app.TestDurationsEditFieldLabel.Text = 'Test Duration (s)';
            app.TestDurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TestDurationsEditField.Limits = [0 120];
            app.TestDurationsEditField.Value = 30;
            app.SampleWindowsEditFieldLabel = uilabel(app.UIFigure);
            app.SampleWindowsEditFieldLabel.Text = 'Sample Window (s)';
            app.SampleWindowsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleWindowsEditField.Limits = [0 120];
            app.SampleWindowsEditField.Value = 1;
            app.CardiacbeatBWHzEditFieldLabel = uilabel(app.UIFigure);
            app.CardiacbeatBWHzEditFieldLabel.Text = 'Cardiac beat BW (Hz)';
            app.CardiacbeatBWHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.CardiacbeatBWHzEditField.Limits = [0 120];
            app.CardiacbeatBWHzEditField.Value = 1;
            app.QRSCmplexHREditFieldLabel = uilabel(app.UIFigure);
            app.QRSCmplexHREditFieldLabel.Text = 'QRS Cmplex. / HR ';
            app.QRSCmplexHREditField = uieditfield(app.UIFigure, 'numeric');
            app.QRSCmplexHREditField.Limits = [0 1];
            app.QRSCmplexHREditField.Value = 0.1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = cardiopulmonary_monitor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end